classdef CapacitorStatics < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MaterialPCSDropDown             matlab.ui.control.DropDown
        MaterialPCSDropDownLabel        matlab.ui.control.Label
        OutputsLabel                    matlab.ui.control.Label
        InputsLabel                     matlab.ui.control.Label
        LeakageCurrentEditField         matlab.ui.control.NumericEditField
        LeakageCurrentEditFieldLabel    matlab.ui.control.Label
        BreakdownVoltageEditField       matlab.ui.control.NumericEditField
        BreakdownVoltageEditFieldLabel  matlab.ui.control.Label
        ConductanceEditField            matlab.ui.control.NumericEditField
        ConductanceEditFieldLabel       matlab.ui.control.Label
        CapacitanceEditField            matlab.ui.control.NumericEditField
        CapacitanceEditFieldLabel       matlab.ui.control.Label
        GetValuesButton                 matlab.ui.control.Button
        OuterRadiusCSEditField          matlab.ui.control.NumericEditField
        OuterRadiusCSEditFieldLabel     matlab.ui.control.Label
        InnerRadiusCSEditField          matlab.ui.control.NumericEditField
        InnerRadiusCSEditFieldLabel     matlab.ui.control.Label
        HeightCEditField                matlab.ui.control.NumericEditField
        HeightCEditFieldLabel           matlab.ui.control.Label
        WidthPEditField                 matlab.ui.control.NumericEditField
        WidthPEditFieldLabel            matlab.ui.control.Label
        LengthPEditField                matlab.ui.control.NumericEditField
        LengthPEditFieldLabel           matlab.ui.control.Label
        VoltagePCSEditField             matlab.ui.control.NumericEditField
        VoltagePCSLabel                 matlab.ui.control.Label
        DistancePCSEditField            matlab.ui.control.NumericEditField
        DistancePCSEditFieldLabel       matlab.ui.control.Label
        CapacitorTypeButtonGroup        matlab.ui.container.ButtonGroup
        SphericalButton                 matlab.ui.control.RadioButton
        CylindricalButton               matlab.ui.control.RadioButton
        ParallelPlateButton             matlab.ui.control.RadioButton
        UIAxes                          matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GetValuesButton
        function GetValuesButtonPushed(app, event)
                %define variables
                material = app.MaterialPCSDropDown.Value
                length = app.LengthPEditField.Value;
                width = app.WidthPEditField.Value;
                distance = app.DistancePCSEditField.Value;
                voltage = app.VoltagePCSEditField.Value;
                height = app.HeightCEditField.Value;
                inner_radius = app.InnerRadiusCSEditField.Value;
                outer_radius = app.OuterRadiusCSEditField.Value;
                %define constants table
                e0 = 8.854e-12;
                dielectric_constants = readtable('Dielectric_Table.xlsx');
                disp(dielectric_constants);
                names = ["Air", "Alumina", "Barium Titanate", "Glass", "Ice", "Mica", "Polyethylene", "Polystyrene", "Quartz (fused)", "Silicon", "Soil", "Teflon", "Water (distilled)"];
                for i = 1:13
                    if names(i) == material
                        index = i;
                    end
                end
                info = table2array(dielectric_constants(index,2:4));
                x = 0:distance/100:distance*2;
                %call each shape
                if app.ParallelPlateButton.Value
                    %calculate capacitance
                    app.CapacitanceEditField.Value = e0*info(1)*length*width/distance;
                    %calculate conductance
                    app.ConductanceEditField.Value = info(3)*length*width/distance;
                    %calcualte leakage current
                    app.LeakageCurrentEditField.Value = info(3)*voltage*length*width/distance;
                    %calcultae vbr
                    app.BreakdownVoltageEditField.Value = distance*info(2);
                    plot(app.UIAxes, x, info(3)/(info(1)*e0), '-r');
                    axis(app.UIAxes, 'tight')
                end
                if app.CylindricalButton.Value
                    %calculate capacitance
                    app.CapacitanceEditField.Value = 2*pi*info(1)*e0*height/(log(outer_radius/inner_radius));
                    %calculate conductance
                    app.ConductanceEditField.Value = info(3)*height;
                    %calcualte leakage current
                    app.LeakageCurrentEditField.Value = info(3)*height*voltage;
                    %calcultae vbr
                    app.BreakdownVoltageEditField.Value = distance*info(2);
                end
                if app.SphericalButton.Value
                    %calculate capacitance
                    app.CapacitanceEditField.Value = info(1)*4*pi*e0/(1/inner_radius - 1/outer_radius);
                    %calculate conductance
                    app.ConductanceEditField.Value = info(3)*height;
                    %calcualte leakage current
                    app.LeakageCurrentEditField.Value = info(3)*height*voltage;
                    %calcultae vbr
                    app.BreakdownVoltageEditField.Value = distance*info(2);
                end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'E-Field vs Distance')
            xlabel(app.UIAxes, 'Distance')
            ylabel(app.UIAxes, 'E-Field')
            zlabel(app.UIAxes, 'Z')
            app.CapacitorTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.CapacitorTypeButtonGroup.Title = 'Capacitor Type';
            app.ParallelPlateButton = uiradiobutton(app.CapacitorTypeButtonGroup);
            app.ParallelPlateButton.Text = 'Parallel Plate';
            app.ParallelPlateButton.Value = true;
            app.CylindricalButton = uiradiobutton(app.CapacitorTypeButtonGroup);
            app.CylindricalButton.Text = 'Cylindrical';
            app.SphericalButton = uiradiobutton(app.CapacitorTypeButtonGroup);
            app.SphericalButton.Text = 'Spherical';
            app.DistancePCSEditFieldLabel = uilabel(app.UIFigure);
            app.DistancePCSEditFieldLabel.Text = 'Distance (P, C, S)';
            app.DistancePCSEditField = uieditfield(app.UIFigure, 'numeric');
            app.VoltagePCSLabel = uilabel(app.UIFigure);
            app.VoltagePCSLabel.Text = 'Voltage (P, C, S)';
            app.VoltagePCSEditField = uieditfield(app.UIFigure, 'numeric');
            app.LengthPEditFieldLabel = uilabel(app.UIFigure);
            app.LengthPEditFieldLabel.Text = 'Length (P)';
            app.LengthPEditField = uieditfield(app.UIFigure, 'numeric');
            app.WidthPEditFieldLabel = uilabel(app.UIFigure);
            app.WidthPEditFieldLabel.Text = 'Width (P)';
            app.WidthPEditField = uieditfield(app.UIFigure, 'numeric');
            app.HeightCEditFieldLabel = uilabel(app.UIFigure);
            app.HeightCEditFieldLabel.Text = 'Height (C)';
            app.HeightCEditField = uieditfield(app.UIFigure, 'numeric');
            app.InnerRadiusCSEditFieldLabel = uilabel(app.UIFigure);
            app.InnerRadiusCSEditFieldLabel.Text = 'Inner Radius (C, S)';
            app.InnerRadiusCSEditField = uieditfield(app.UIFigure, 'numeric');
            app.OuterRadiusCSEditFieldLabel = uilabel(app.UIFigure);
            app.OuterRadiusCSEditFieldLabel.Text = 'Outer Radius (C, S)';
            app.OuterRadiusCSEditField = uieditfield(app.UIFigure, 'numeric');
            app.GetValuesButton = uibutton(app.UIFigure, 'push');
            app.GetValuesButton.ButtonPushedFcn = createCallbackFcn(app, @GetValuesButtonPushed, true);
            app.GetValuesButton.Text = 'Get Values';
            app.CapacitanceEditFieldLabel = uilabel(app.UIFigure);
            app.CapacitanceEditFieldLabel.Text = 'Capacitance';
            app.CapacitanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.ConductanceEditFieldLabel = uilabel(app.UIFigure);
            app.ConductanceEditFieldLabel.Text = 'Conductance';
            app.ConductanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.BreakdownVoltageEditFieldLabel = uilabel(app.UIFigure);
            app.BreakdownVoltageEditFieldLabel.Text = 'Breakdown Voltage';
            app.BreakdownVoltageEditField = uieditfield(app.UIFigure, 'numeric');
            app.LeakageCurrentEditFieldLabel = uilabel(app.UIFigure);
            app.LeakageCurrentEditFieldLabel.Text = 'Leakage Current';
            app.LeakageCurrentEditField = uieditfield(app.UIFigure, 'numeric');
            app.InputsLabel = uilabel(app.UIFigure);
            app.InputsLabel.Text = 'Inputs';
            app.OutputsLabel = uilabel(app.UIFigure);
            app.OutputsLabel.Text = 'Outputs';
            app.MaterialPCSDropDownLabel = uilabel(app.UIFigure);
            app.MaterialPCSDropDownLabel.Text = 'Material (P, C, S)';
            app.MaterialPCSDropDown = uidropdown(app.UIFigure);
            app.MaterialPCSDropDown.Items = {'Air', 'Alumina', 'Barium Titanate', 'Glass', 'Ice', 'Mica', 'Polyethylene', 'Polystyrene', 'Quartz (fused)', 'Silicon', 'Soil', 'Teflon', 'Water (distilled)', 'Seawater'};
            app.MaterialPCSDropDown.Value = 'Air';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CapacitorStatics

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end